classdef designMetaTile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        KeyValuePairsforLocalFolderPanel  matlab.ui.container.Panel
        AddButton                       matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        ListBoxLabel                    matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        NewKeyEditFieldLabel            matlab.ui.control.Label
        NewKeyEditField                 matlab.ui.control.EditField
        NewValueEditFieldLabel          matlab.ui.control.Label
        NewValueEditField               matlab.ui.control.EditField
        PreKeyValuePathEditFieldLabel   matlab.ui.control.Label
        PreKeyValuePathEditField        matlab.ui.control.EditField
        PostKeyValuePathEditFieldLabel  matlab.ui.control.Label
        PostKeyValuePathEditField       matlab.ui.control.EditField
        LocalPathEditFieldLabel         matlab.ui.control.Label
        LocalPathEditField              matlab.ui.control.EditField
        KeyValuePairsforFilePanel       matlab.ui.container.Panel
        AddButton_2                     matlab.ui.control.Button
        RemoveButton_2                  matlab.ui.control.Button
        NewKeyEditField_2Label          matlab.ui.control.Label
        NewKeyEditField_2               matlab.ui.control.EditField
        NewValueEditField_2Label        matlab.ui.control.Label
        NewValueEditField_2             matlab.ui.control.EditField
        ListBox_2Label                  matlab.ui.control.Label
        ListBox_2                       matlab.ui.control.ListBox
        UIAxes                          matlab.ui.control.UIAxes
        AlgorithmRoutingDropDownLabel   matlab.ui.control.Label
        AlgorithmRoutingDropDown        matlab.ui.control.DropDown
        DeviceTypeDropDownLabel         matlab.ui.control.Label
        DeviceTypeDropDown              matlab.ui.control.DropDown
        ResolutionDropDownLabel         matlab.ui.control.Label
        ResolutionDropDown              matlab.ui.control.DropDown
        DurationmEditFieldLabel         matlab.ui.control.Label
        DurationmEditField              matlab.ui.control.NumericEditField
        UIAxes_2                        matlab.ui.control.UIAxes
        TimedelaymEditFieldLabel        matlab.ui.control.Label
        TimedelaymEditField             matlab.ui.control.NumericEditField
        UIAxes_3                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        basePath = '/imageStore/' % Description
        currentPath = '';
        
        
        strMessageBody; % Description
        strMessageFrom;
        strMessageTo;
        
        stpMessageBody; % Description
        stpMessageFrom;
        stpMessageTo;
    end

    methods (Access = private)
    
        function results = refreshLocalPath(app)
            pathDisplay{1} = app.basePath;
            pathDisplay{2} = app.PreKeyValuePathEditField.Value;
            kvpString = [];
            for e = 1:numel(app.ListBox.Items)
                kvpString = [kvpString app.ListBox.Items{e}];
            end
            pathDisplay{3} = [kvpString];
            pathDisplay{4} = app.PostKeyValuePathEditField.Value;
            pth = [];
            for e = 1:numel(pathDisplay)
                tmp = pathDisplay{e};
                if ~isempty(tmp)
                    if ~strcmp(tmp(end),filesep)
                        tmp = [tmp filesep];
                    end
                end
                pth = [pth tmp];
            end
            app.LocalPathEditField.Value = pth;
            app.currentPath = pth;
            I = generateQRtile(app.currentPath,[100 100]);
            imshow(I,'Parent',app.UIAxes);
            app.UIAxes.XLim = [0 size(I,2)];
            app.UIAxes.YLim = [0 size(I,1)];
            drawnow
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DurationmEditField.Enable = 'off';
            app.TimedelaymEditField.Enable = 'off';
            app.LocalPathEditField.Editable = 'off';
            app.UIAxes.Title.String = 'Meta Tile QR';
            app.UIAxes.XLabel.String = '';
            app.UIAxes.YLabel.String = '';
            refreshLocalPath(app);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
             if isempty(app.NewValueEditField.Value) && isempty(app.NewKeyEditField.Value)
                app.NewValueEditField.Value = 'newValue';
                app.NewKeyEditField.Value = 'newKey';
            elseif ~isempty(app.NewValueEditField.Value) && isempty(app.NewKeyEditField.Value)
                app.NewKeyEditField.Value = 'newKey';
            elseif isempty(app.NewValueEditField.Value) && ~isempty(app.NewKeyEditField.Value)
                app.NewValueEditField.Value = 'newValue';
            else
                kvp = ['{' app.NewKeyEditField.Value '}_{' app.NewValueEditField.Value '}'];
                app.ListBox.Items{end+1} = kvp;
                app.NewKeyEditField.Value = '';
                app.NewValueEditField.Value = '';
                refreshLocalPath(app);
             end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
           app.ListBox.Items = setdiff(app.ListBox.Items,app.ListBox.Value);
           refreshLocalPath(app);
        end

        % Value changed function: PreKeyValuePathEditField
        function PreKeyValuePathEditFieldValueChanged(app, event)
            value = app.PreKeyValuePathEditField.Value;
            refreshLocalPath(app);
        end

        % Value changed function: PostKeyValuePathEditField
        function PostKeyValuePathEditFieldValueChanged(app, event)
            value = app.PostKeyValuePathEditField.Value;
            refreshLocalPath(app);
        end

        % Button pushed function: AddButton_2
        function AddButton_2Pushed(app, event)
            if isempty(app.NewValueEditField_2.Value) && isempty(app.NewKeyEditField_2.Value)
                app.NewValueEditField_2.Value = 'newValue';
                app.NewKeyEditField_2.Value = 'newKey';
            elseif ~isempty(app.NewValueEditField_2.Value) && isempty(app.NewKeyEditField_2.Value)
                app.NewKeyEditField_2.Value = 'newKey';
            elseif isempty(app.NewValueEditField_2.Value) && ~isempty(app.NewKeyEditField_2.Value)
                app.NewValueEditField_2.Value = 'newValue';
            else
                kvp = ['{' app.NewKeyEditField_2.Value '}_{' app.NewValueEditField_2.Value '}'];
                app.ListBox_2.Items{end+1} = kvp;
                app.NewKeyEditField_2.Value = '';
                app.NewValueEditField_2.Value = '';
            end
        end

        % Button pushed function: RemoveButton_2
        function RemoveButton_2Pushed(app, event)
           app.ListBox_2.Items = setdiff(app.ListBox_2.Items,app.ListBox_2.Value);
           refreshLocalPath(app);
        end

        % Value changed function: DeviceTypeDropDown
        function DeviceTypeDropDownValueChanged(app, event)
            value = app.DeviceTypeDropDown.Value;
            if contains(lower(value),'loop')
                app.DurationmEditField.Enable = 'on';
                app.TimedelaymEditField.Enable = 'on';
            else
                app.DurationmEditField.Enable = 'off';
                app.TimedelaymEditField.Enable = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.KeyValuePairsforLocalFolderPanel = uipanel(app.UIFigure);
            app.KeyValuePairsforLocalFolderPanel.Title = 'Key Value Pairs for Local Folder';
            app.AddButton = uibutton(app.KeyValuePairsforLocalFolderPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.KeyValuePairsforLocalFolderPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.ListBoxLabel = uilabel(app.KeyValuePairsforLocalFolderPanel);
            app.ListBoxLabel.Text = '';
            app.ListBox = uilistbox(app.KeyValuePairsforLocalFolderPanel);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.NewKeyEditFieldLabel = uilabel(app.KeyValuePairsforLocalFolderPanel);
            app.NewKeyEditFieldLabel.Text = 'New Key';
            app.NewKeyEditField = uieditfield(app.KeyValuePairsforLocalFolderPanel, 'text');
            app.NewValueEditFieldLabel = uilabel(app.KeyValuePairsforLocalFolderPanel);
            app.NewValueEditFieldLabel.Text = 'New Value';
            app.NewValueEditField = uieditfield(app.KeyValuePairsforLocalFolderPanel, 'text');
            app.PreKeyValuePathEditFieldLabel = uilabel(app.UIFigure);
            app.PreKeyValuePathEditFieldLabel.Text = 'Pre Key Value Path';
            app.PreKeyValuePathEditField = uieditfield(app.UIFigure, 'text');
            app.PreKeyValuePathEditField.ValueChangedFcn = createCallbackFcn(app, @PreKeyValuePathEditFieldValueChanged, true);
            app.PostKeyValuePathEditFieldLabel = uilabel(app.UIFigure);
            app.PostKeyValuePathEditFieldLabel.Text = 'Post Key Value Path';
            app.PostKeyValuePathEditField = uieditfield(app.UIFigure, 'text');
            app.PostKeyValuePathEditField.ValueChangedFcn = createCallbackFcn(app, @PostKeyValuePathEditFieldValueChanged, true);
            app.LocalPathEditFieldLabel = uilabel(app.UIFigure);
            app.LocalPathEditFieldLabel.Text = 'Local Path';
            app.LocalPathEditField = uieditfield(app.UIFigure, 'text');
            app.KeyValuePairsforFilePanel = uipanel(app.UIFigure);
            app.KeyValuePairsforFilePanel.Title = 'Key Value Pairs for File';
            app.AddButton_2 = uibutton(app.KeyValuePairsforFilePanel, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Text = 'Add';
            app.RemoveButton_2 = uibutton(app.KeyValuePairsforFilePanel, 'push');
            app.RemoveButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveButton_2Pushed, true);
            app.RemoveButton_2.Text = 'Remove';
            app.NewKeyEditField_2Label = uilabel(app.KeyValuePairsforFilePanel);
            app.NewKeyEditField_2Label.Text = 'New Key';
            app.NewKeyEditField_2 = uieditfield(app.KeyValuePairsforFilePanel, 'text');
            app.NewValueEditField_2Label = uilabel(app.KeyValuePairsforFilePanel);
            app.NewValueEditField_2Label.Text = 'New Value';
            app.NewValueEditField_2 = uieditfield(app.KeyValuePairsforFilePanel, 'text');
            app.ListBox_2Label = uilabel(app.KeyValuePairsforFilePanel);
            app.ListBox_2Label.Text = '';
            app.ListBox_2 = uilistbox(app.KeyValuePairsforFilePanel);
            app.ListBox_2.Items = {};
            app.ListBox_2.Value = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AlgorithmRoutingDropDownLabel = uilabel(app.UIFigure);
            app.AlgorithmRoutingDropDownLabel.Text = 'Algorithm Routing';
            app.AlgorithmRoutingDropDown = uidropdown(app.UIFigure);
            app.DeviceTypeDropDownLabel = uilabel(app.UIFigure);
            app.DeviceTypeDropDownLabel.Text = 'Device Type';
            app.DeviceTypeDropDown = uidropdown(app.UIFigure);
            app.DeviceTypeDropDown.Items = {'Camera', 'Scanner', 'Scanner Loop', 'Camera Loop'};
            app.DeviceTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceTypeDropDownValueChanged, true);
            app.DeviceTypeDropDown.Value = 'Camera';
            app.ResolutionDropDownLabel = uilabel(app.UIFigure);
            app.ResolutionDropDownLabel.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.UIFigure);
            app.DurationmEditFieldLabel = uilabel(app.UIFigure);
            app.DurationmEditFieldLabel.Text = 'Duration (m)';
            app.DurationmEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.TimedelaymEditFieldLabel = uilabel(app.UIFigure);
            app.TimedelaymEditFieldLabel.Text = 'Time delay (m)';
            app.TimedelaymEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = designMetaTile

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end