classdef manageDevices < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CollectImagesTab                matlab.ui.container.Tab
        NewTileTab                      matlab.ui.container.Tab
        KeyValuePairsforLocalFolderPanel  matlab.ui.container.Panel
        AddButton                       matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        ListBoxLabel                    matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        NewKeyEditFieldLabel            matlab.ui.control.Label
        NewKeyEditField                 matlab.ui.control.EditField
        NewValueEditFieldLabel          matlab.ui.control.Label
        NewValueEditField               matlab.ui.control.EditField
        KeyValuePairsforFilePanel       matlab.ui.container.Panel
        AddButton_2                     matlab.ui.control.Button
        RemoveButton_2                  matlab.ui.control.Button
        NewKeyEditField_2Label          matlab.ui.control.Label
        NewKeyEditField_2               matlab.ui.control.EditField
        ListBox_2Label                  matlab.ui.control.Label
        ListBox_2                       matlab.ui.control.ListBox
        NewValueEditField_2Label        matlab.ui.control.Label
        NewValueEditField_2             matlab.ui.control.EditField
        PreKeyValuePathEditFieldLabel   matlab.ui.control.Label
        PreKeyValuePathEditField        matlab.ui.control.EditField
        PostKeyValuePathEditFieldLabel  matlab.ui.control.Label
        PostKeyValuePathEditField       matlab.ui.control.EditField
        LocalPathEditFieldLabel         matlab.ui.control.Label
        LocalPathEditField              matlab.ui.control.EditField
        AlgorithmRoutingDropDownLabel   matlab.ui.control.Label
        AlgorithmRoutingDropDown        matlab.ui.control.DropDown
        DeviceTypeDropDownLabel         matlab.ui.control.Label
        DeviceTypeDropDown              matlab.ui.control.DropDown
        ResolutionDropDownLabel         matlab.ui.control.Label
        ResolutionDropDown              matlab.ui.control.DropDown
        DurationmEditFieldLabel         matlab.ui.control.Label
        DurationmEditField              matlab.ui.control.NumericEditField
        TimedelaymEditFieldLabel        matlab.ui.control.Label
        TimedelaymEditField             matlab.ui.control.NumericEditField
        TitleEditFieldLabel             matlab.ui.control.Label
        TitleEditField                  matlab.ui.control.EditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        TriggerSequenceButton           matlab.ui.control.RadioButton
        SingleTriggerButton             matlab.ui.control.RadioButton
        SampleLabelsButton              matlab.ui.control.RadioButton
        BrowseButton                    matlab.ui.control.Button
        DataFileEditFieldLabel          matlab.ui.control.Label
        DataFileEditField               matlab.ui.control.EditField
        StartTilePanel                  matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        SampleTilePanel                 matlab.ui.container.Panel
        UIAxes_3                        matlab.ui.control.UIAxes
        EndTilePanel                    matlab.ui.container.Panel
        UIAxes_4                        matlab.ui.control.UIAxes
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        AddPortButton                   matlab.ui.control.Button
        AddTileSeqButton                matlab.ui.control.Button
        RemovePortButton                matlab.ui.control.Button
        RemoveTileSeqButton             matlab.ui.control.Button
        ComputerListBoxLabel            matlab.ui.control.Label
        ComputerListBox                 matlab.ui.control.ListBox
        PortsListBoxLabel               matlab.ui.control.Label
        PortsListBox                    matlab.ui.control.ListBox
        TileSequencesListBoxLabel       matlab.ui.control.Label
        TileSequencesListBox            matlab.ui.control.ListBox
    end


    properties (Access = private)
        store = objectFSstore();    % default data store
        
        basePath = '/imageStore/';  % Description
        currentPath = '';           % current path
        curTileSeq;                 % Description
        deviceData;                 % start message data - aka device message
        fileFolderMsg;              % message data for folder and file
        currentFile = '';           % Description
    end

    methods (Access = private)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % refresh the algorithm menu list
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function results = refreshAlgorithmMenu(app)
            tmpMenu = [getenv('PHYTO_TMP') 'algoRouteTable.json'];
            computerList = refreshComputerlist(app);
            refreshUSBlist(app,computerList(1)); url = ['https://data.cyverse.org/dav-anon/iplant/home/nmiller/publicData/algoRouteTable.json'];
            filename = websave(tmpMenu, url);
            data = fileread(filename);
            menuData = jsondecode(data);
            menuItems{1} = '';
            for e = 1:numel(menuData)
                menuItems{e+1} = menuData(e).algorithmName;
            end
            app.AlgorithmRoutingDropDown.Items = menuItems;
        end
        % refresh the menu for UBS devices
        function results = refreshUSBlist(app,computer)
            if ~isempty(computer.usbPorts)
                [usbObjects,fileList] = findObjects('ptrList',[computer.usbPorts.refs]);
                app.PortsListBox.Items = {usbObjects.hName};
                app.PortsListBox.ItemsData = fileList;
                %refreshUSBportDetails(app,usbObjects(1));
                refreshTileSequenceList(app,usbObjects(1));
            end
        end
        % refresh the tile sequence menu list
        function results = refreshTileSequenceList(app,usbObject)
            triggerItems = {};
            if ~isempty(usbObject.triggerList)
                [triggerObj,fileList] = findObjects('ptrList',[usbObject.triggerList.refs]);
                for e = 1:numel(triggerObj)
                    triggerItems{e} = triggerObj(e).title;
                end
            end
            app.TileSequencesListBox.Items = triggerItems;
        end
        % refresh the computer menu list
        function results = refreshComputerlist(app)
            app.store.find('com')
            [computerList,fileList] = findObjects('type','com',1);
            app.ComputerListBox.Items = {computerList.hName};
            app.ComputerListBox.ItemsData = fileList;
            results = computerList;
        end
        
        
        function [] = buildTiles(app)
        
        end


        % refresh the path encoded in the meta data
        function results = refreshLocalPath(app)
            %%%
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % glue the paths 1) basePath
            % 2) preKey 3) key-value pairs 4) postKey
            pathDisplay{1} = app.basePath;
            pathDisplay{2} = app.PreKeyValuePathEditField.Value;
            kvpString = '';
            for e = 1:numel(app.ListBox.Items)
                kvpString = [kvpString app.ListBox.Items{e}];
            end
            pathDisplay{3} = [kvpString];
            pathDisplay{4} = app.PostKeyValuePathEditField.Value;
            % glue them together
            pth = [];
            for e = 1:numel(pathDisplay)
                tmp = pathDisplay{e};
                if ~isempty(tmp)
                    if ~strcmp(tmp(end),filesep)
                        tmp = [tmp filesep];
                    end
                end
                pth = [pth tmp];
            end
            app.LocalPathEditField.Value = pth;
            app.currentPath = pth;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            kvpFileString = '';
            for e = 1:numel(app.ListBox_2.Items)
                kvpFileString = [kvpFileString app.ListBox_2.Items{e}];
            end
            app.currentFile = kvpFileString;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % build and display the QR code
            I = generateQRtile(app.currentPath,[100 100]);
            imshow(I,'Parent',app.UIAxes);
            app.UIAxes.XLim = [0 size(I,2)];
            app.UIAxes.YLim = [0 size(I,1)];
            drawnow
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            app.fileFolderMsg.folderPath = app.currentPath;
            app.fileFolderMsg.fileName = app.currentFile;
            
            
            % update the display
            app.FileNameEditField.Value = app.currentFile;
            
            
            % refresh the data in the message from the tile to the computer
            refreshDeviceMessageData(app);
            
            
        end
        
        
        function results = refreshDeviceMessageData(app)
            app.deviceData.algorithmRouteCode = app.AlgorithmRoutingDropDown.Value;
            app.deviceData.deviceType = app.DeviceTypeDropDown.Value;
            app.deviceData.deviceResolution = app.ResolutionDropDown.Value;
            app.deviceData.timeDelay = app.TimedelaymEditField.Value;
            app.deviceData.timeDuration = app.DurationmEditField.Value;
        end
        
        function results = refreshStartTile(app)
            %deviceType = app.
        end
        
        function results = refreshTileDetails(app)
            DeviceTypeDropDownValueChanged(app);
            refreshStartTile(app);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            refreshLocalPath(app);
            refreshTileDetails(app);
            refreshAlgorithmMenu(app);
            refreshDeviceMessageData(app);
            
            
            app.DurationmEditField.Enable = 'off';
            app.TimedelaymEditField.Enable = 'off';
            app.LocalPathEditField.Editable = 'off';
            app.FileNameEditField.Editable = 'off';
            app.DataFileEditField.Editable = 'off';
            app.UIAxes.Title.String = '';
            app.UIAxes.XLabel.String = '';
            app.UIAxes.YLabel.String = '';
            app.UIAxes.Visible = 'off';
            
            app.UIAxes_3.Title.String = '';
            app.UIAxes_3.XLabel.String = '';
            app.UIAxes_3.YLabel.String = '';
            app.UIAxes_3.Visible = 'off';
            
            app.UIAxes_4.Title.String = '';
            app.UIAxes_4.XLabel.String = '';
            app.UIAxes_4.YLabel.String = '';
            app.UIAxes_4.Visible = 'off';
            
        end

        % Value changed function: PortsListBox
        function PortsListBoxValueChanged(app, event)
            value = app.PortsListBox.Value;
            usbObject = findObjects('file',value);
            refreshTileSequenceList(app,usbObject);
        end

        % Value changed function: ComputerListBox
        function ComputerListBoxValueChanged(app, event)
            jsonFile = app.ComputerListBox.Value;
            [computerObject] = findObjects('file',jsonFile);
            refreshUSBlist(app,computerObject);
        end

        % Button pushed function: AddPortButton
        function AddPortButtonPushed(app, event)
%{            
            h = msgbox('Please UNPLUG the device you want to add.','Addingdevice').
            CMD = ['matlab_usbPortScanner1'];
            [status,result] = system(CMD);
            h = msgbox('Please PLUG IN and POWER the device you want to add.','Addingdevice').
            CMD = ['matlab_usbPortScanner2'];
            [status,result] = system(CMD);
            portData = readtext('~/tmpNewPort');
            portID = portData{1};
            
            d = dialog('Position',[300 300 250 150],'Name','Select One');
    txt = uicontrol('Parent',d,...
           'Style','text',...
           'Position',[20 80 210 40],...
           'String','Select a color');
       
    popup = uicontrol('Parent',d,...
           'Style','popup',...
           'Position',[75 70 100 25],...
           'String',{'Red';'Green';'Blue'},...
           'Callback',@popup_callback);
       
    btn = uicontrol('Parent',d,...
           'Position',[89 20 70 25],...
           'String','Close',...
           'Callback','delete(gcf)');
       
    choice = 'Red';
            %}
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if isempty(app.NewValueEditField.Value) && isempty(app.NewKeyEditField.Value)
                app.NewValueEditField.Value = 'newValue';
                app.NewKeyEditField.Value = 'newKey';
            elseif ~isempty(app.NewValueEditField.Value) && isempty(app.NewKeyEditField.Value)
                app.NewKeyEditField.Value = 'newKey';
            elseif isempty(app.NewValueEditField.Value) && ~isempty(app.NewKeyEditField.Value)
                app.NewValueEditField.Value = 'newValue';
            else
                kvp = ['{' app.NewKeyEditField.Value '}_{' app.NewValueEditField.Value '}'];
                app.ListBox.Items{end+1} = kvp;
                app.NewKeyEditField.Value = '';
                app.NewValueEditField.Value = '';
                refreshLocalPath(app);
             end
        end

        % Value changed function: DeviceTypeDropDown
        function DeviceTypeDropDownValueChanged(app, event)
            value = app.DeviceTypeDropDown.Value;
            if contains(lower(value),'loop')
                app.DurationmEditField.Enable = 'on';
                app.TimedelaymEditField.Enable = 'on';
            else
                app.DurationmEditField.Enable = 'off';
                app.TimedelaymEditField.Enable = 'off';
            end
            if contains(lower(value),'scanner')
                app.ResolutionDropDown.Enable = 'on';
            else
                app.ResolutionDropDown.Enable = 'off';
            end
            refreshDeviceMessageData(app);
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
           app.ListBox.Items = setdiff(app.ListBox.Items,app.ListBox.Value);
           refreshLocalPath(app);
        end

        % Button pushed function: AddButton_2
        function AddButton_2Pushed(app, event)
            if isempty(app.NewValueEditField_2.Value) && isempty(app.NewKeyEditField_2.Value)
                app.NewValueEditField_2.Value = 'newValue';
                app.NewKeyEditField_2.Value = 'newKey';
            elseif ~isempty(app.NewValueEditField_2.Value) && isempty(app.NewKeyEditField_2.Value)
                app.NewKeyEditField_2.Value = 'newKey';
            elseif isempty(app.NewValueEditField_2.Value) && ~isempty(app.NewKeyEditField_2.Value)
                app.NewValueEditField_2.Value = 'newValue';
            else
                kvp = ['{' app.NewKeyEditField_2.Value '}_{' app.NewValueEditField_2.Value '}'];
                app.ListBox_2.Items{end+1} = kvp;
                app.NewKeyEditField_2.Value = '';
                app.NewValueEditField_2.Value = '';
                refreshLocalPath(app);
            end
        end

        % Button pushed function: RemoveButton_2
        function RemoveButton_2Pushed(app, event)
           app.ListBox_2.Items = setdiff(app.ListBox_2.Items,app.ListBox_2.Value);
           refreshLocalPath(app);
        end

        % Value changed function: PreKeyValuePathEditField
        function PreKeyValuePathEditFieldValueChanged(app, event)
            value = app.PreKeyValuePathEditField.Value;
            refreshLocalPath(app);
        end

        % Value changed function: PostKeyValuePathEditField
        function PostKeyValuePathEditFieldValueChanged(app, event)
            value = app.PostKeyValuePathEditField.Value;
            refreshLocalPath(app);
        end

        % Button pushed function: AddTileSeqButton
        function AddTileSeqButtonPushed(app, event)
            
            app.TitleEditField.Value = 'New Title';
            % get the current USB object and encode a json pointer
            curUSB = app.PortsListBox.Value;
            usbObject = findObjects('file',curUSB);
            usbObjectPtr = projectPtr(usbObject);
            usbObjectPtr = jsonencode(usbObjectPtr);
            % make a new tile sequence
            app.curTileSeq = genAPI('tileSeq',app.TitleEditField.Value,2,1,usbObjectPtr);
            %app.TabGroup.SelectedTab = app.NewTileTab;
            
        end

        % Value changed function: TileSequencesListBox
        function TileSequencesListBoxValueChanged(app, event)
            value = app.TileSequencesListBox.Value;
            
        end

        % Button pushed function: RemovePortButton
        function RemovePortButtonPushed(app, event)
            usbJSONfile = app.PortsListBox.Value;
            computerJSONfile = app.ComputerListBox.Value;
            computerObject = findObjects('file',computerJSONfile);
            usbObject = findObjects('file',usbJSONfile);
            
            % search for object in list
            for e = 1:numel(computerObject.usbPorts)
                b(e) = comparePtrs(computerObject.usbPorts(e),usbObject);
            end
            % find object to remove
            ridx = find(b);
            
            % remove object from list
            computerObject.usbPorts(ridx) = [];
            writeObject(computerObject);
            % recycle the usb port object and its tiles
            [newName] = recycleObject(usbObject,'triggerList');
            % reload/refresh from disk
            computerObject = findObjects('file',computerJSONfile);
            refreshUSBlist(app,computerObject)
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Single Trigger')
                app.StartTilePanel.Visible = 'off';
                app.EndTilePanel.Visible = 'off';
            else
                app.StartTilePanel.Visible = 'on';
                app.EndTilePanel.Visible = 'on';
            end
                  
            app.UIAxes.Title.String = '';
            app.UIAxes.XLabel.String = '';
            app.UIAxes.YLabel.String = '';
            
            app.UIAxes_3.Title.String = '';
            app.UIAxes_3.XLabel.String = '';
            app.UIAxes_3.YLabel.String = '';
            
            app.UIAxes_4.Title.String = '';
            app.UIAxes_4.XLabel.String = '';
            app.UIAxes_4.YLabel.String = '';
        end

        % Value changed function: AlgorithmRoutingDropDown
        function AlgorithmRoutingDropDownValueChanged(app, event)
            value = app.AlgorithmRoutingDropDown.Value;
            refreshDeviceMessageData(app);
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            value = app.ListBox_2.Value;
            
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            value = app.FileNameEditField.Value;
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.file = uigetfile;
            app.DataFileEditField.Value = app.file;
        end

        % Value changed function: ResolutionDropDown
        function ResolutionDropDownValueChanged(app, event)
            value = app.ResolutionDropDown.Value;
            refreshDeviceMessageData(app);
        end

        % Value changed function: TimedelaymEditField
        function TimedelaymEditFieldValueChanged(app, event)
            value = app.TimedelaymEditField.Value;
            refreshDeviceMessageData(app);
        end

        % Value changed function: DurationmEditField
        function DurationmEditFieldValueChanged(app, event)
            value = app.DurationmEditField.Value;
            refreshDeviceMessageData(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CollectImagesTab = uitab(app.TabGroup);
            app.CollectImagesTab.Title = 'Collect Images';
            app.NewTileTab = uitab(app.TabGroup);
            app.NewTileTab.Title = 'New Tile';
            app.KeyValuePairsforLocalFolderPanel = uipanel(app.NewTileTab);
            app.KeyValuePairsforLocalFolderPanel.Title = 'Key Value Pairs for Local Folder';
            app.AddButton = uibutton(app.KeyValuePairsforLocalFolderPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.KeyValuePairsforLocalFolderPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.ListBoxLabel = uilabel(app.KeyValuePairsforLocalFolderPanel);
            app.ListBoxLabel.Text = '';
            app.ListBox = uilistbox(app.KeyValuePairsforLocalFolderPanel);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.NewKeyEditFieldLabel = uilabel(app.KeyValuePairsforLocalFolderPanel);
            app.NewKeyEditFieldLabel.Text = 'New Key';
            app.NewKeyEditField = uieditfield(app.KeyValuePairsforLocalFolderPanel, 'text');
            app.NewValueEditFieldLabel = uilabel(app.KeyValuePairsforLocalFolderPanel);
            app.NewValueEditFieldLabel.Text = 'New Value';
            app.NewValueEditField = uieditfield(app.KeyValuePairsforLocalFolderPanel, 'text');
            app.KeyValuePairsforFilePanel = uipanel(app.NewTileTab);
            app.KeyValuePairsforFilePanel.Title = 'Key Value Pairs for File';
            app.AddButton_2 = uibutton(app.KeyValuePairsforFilePanel, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Text = 'Add';
            app.RemoveButton_2 = uibutton(app.KeyValuePairsforFilePanel, 'push');
            app.RemoveButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveButton_2Pushed, true);
            app.RemoveButton_2.Text = 'Remove';
            app.NewKeyEditField_2Label = uilabel(app.KeyValuePairsforFilePanel);
            app.NewKeyEditField_2Label.Text = 'New Key';
            app.NewKeyEditField_2 = uieditfield(app.KeyValuePairsforFilePanel, 'text');
            app.ListBox_2Label = uilabel(app.KeyValuePairsforFilePanel);
            app.ListBox_2Label.Text = '';
            app.ListBox_2 = uilistbox(app.KeyValuePairsforFilePanel);
            app.ListBox_2.Items = {};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = {};
            app.NewValueEditField_2Label = uilabel(app.KeyValuePairsforFilePanel);
            app.NewValueEditField_2Label.Text = 'New Value';
            app.NewValueEditField_2 = uieditfield(app.KeyValuePairsforFilePanel, 'text');
            app.PreKeyValuePathEditFieldLabel = uilabel(app.NewTileTab);
            app.PreKeyValuePathEditFieldLabel.Text = 'Pre Key Value Path';
            app.PreKeyValuePathEditField = uieditfield(app.NewTileTab, 'text');
            app.PreKeyValuePathEditField.ValueChangedFcn = createCallbackFcn(app, @PreKeyValuePathEditFieldValueChanged, true);
            app.PostKeyValuePathEditFieldLabel = uilabel(app.NewTileTab);
            app.PostKeyValuePathEditFieldLabel.Text = 'Post Key Value Path';
            app.PostKeyValuePathEditField = uieditfield(app.NewTileTab, 'text');
            app.PostKeyValuePathEditField.ValueChangedFcn = createCallbackFcn(app, @PostKeyValuePathEditFieldValueChanged, true);
            app.LocalPathEditFieldLabel = uilabel(app.NewTileTab);
            app.LocalPathEditFieldLabel.Text = 'Local Path';
            app.LocalPathEditField = uieditfield(app.NewTileTab, 'text');
            app.AlgorithmRoutingDropDownLabel = uilabel(app.NewTileTab);
            app.AlgorithmRoutingDropDownLabel.Text = 'Algorithm Routing';
            app.AlgorithmRoutingDropDown = uidropdown(app.NewTileTab);
            app.AlgorithmRoutingDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmRoutingDropDownValueChanged, true);
            app.DeviceTypeDropDownLabel = uilabel(app.NewTileTab);
            app.DeviceTypeDropDownLabel.Text = 'Device Type';
            app.DeviceTypeDropDown = uidropdown(app.NewTileTab);
            app.DeviceTypeDropDown.Items = {'Camera', 'Scanner', 'Scanner Loop', 'Camera Loop'};
            app.DeviceTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceTypeDropDownValueChanged, true);
            app.DeviceTypeDropDown.Value = 'Camera';
            app.ResolutionDropDownLabel = uilabel(app.NewTileTab);
            app.ResolutionDropDownLabel.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.NewTileTab);
            app.ResolutionDropDown.Items = {'300', '600', '1200', '2400'};
            app.ResolutionDropDown.ValueChangedFcn = createCallbackFcn(app, @ResolutionDropDownValueChanged, true);
            app.ResolutionDropDown.Value = '300';
            app.DurationmEditFieldLabel = uilabel(app.NewTileTab);
            app.DurationmEditFieldLabel.Text = 'Duration (m)';
            app.DurationmEditField = uieditfield(app.NewTileTab, 'numeric');
            app.DurationmEditField.ValueChangedFcn = createCallbackFcn(app, @DurationmEditFieldValueChanged, true);
            app.TimedelaymEditFieldLabel = uilabel(app.NewTileTab);
            app.TimedelaymEditFieldLabel.Text = 'Time delay (m)';
            app.TimedelaymEditField = uieditfield(app.NewTileTab, 'numeric');
            app.TimedelaymEditField.ValueChangedFcn = createCallbackFcn(app, @TimedelaymEditFieldValueChanged, true);
            app.TitleEditFieldLabel = uilabel(app.NewTileTab);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.NewTileTab, 'text');
            app.ButtonGroup = uibuttongroup(app.NewTileTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.TriggerSequenceButton = uiradiobutton(app.ButtonGroup);
            app.TriggerSequenceButton.Text = 'Trigger Sequence';
            app.TriggerSequenceButton.Value = true;
            app.SingleTriggerButton = uiradiobutton(app.ButtonGroup);
            app.SingleTriggerButton.Text = 'Single Trigger';
            app.SampleLabelsButton = uiradiobutton(app.ButtonGroup);
            app.SampleLabelsButton.Text = 'Sample Labels';
            app.BrowseButton = uibutton(app.NewTileTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.DataFileEditFieldLabel = uilabel(app.NewTileTab);
            app.DataFileEditFieldLabel.Text = 'Data File';
            app.DataFileEditField = uieditfield(app.NewTileTab, 'text');
            app.StartTilePanel = uipanel(app.NewTileTab);
            app.StartTilePanel.Title = '                          Start Tile';
            app.UIAxes = uiaxes(app.StartTilePanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SampleTilePanel = uipanel(app.NewTileTab);
            app.SampleTilePanel.Title = '                          Sample Tile';
            app.UIAxes_3 = uiaxes(app.SampleTilePanel);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.EndTilePanel = uipanel(app.NewTileTab);
            app.EndTilePanel.Title = '                          End Tile';
            app.UIAxes_4 = uiaxes(app.EndTilePanel);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.FileNameEditFieldLabel = uilabel(app.NewTileTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.NewTileTab, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.AddPortButton = uibutton(app.NewTileTab, 'push');
            app.AddPortButton.ButtonPushedFcn = createCallbackFcn(app, @AddPortButtonPushed, true);
            app.AddPortButton.Text = 'Add Port';
            app.AddTileSeqButton = uibutton(app.NewTileTab, 'push');
            app.AddTileSeqButton.ButtonPushedFcn = createCallbackFcn(app, @AddTileSeqButtonPushed, true);
            app.AddTileSeqButton.Text = 'Add Tile Seq';
            app.RemovePortButton = uibutton(app.NewTileTab, 'push');
            app.RemovePortButton.ButtonPushedFcn = createCallbackFcn(app, @RemovePortButtonPushed, true);
            app.RemovePortButton.Text = 'Remove Port';
            app.RemoveTileSeqButton = uibutton(app.NewTileTab, 'push');
            app.RemoveTileSeqButton.Text = 'Remove Tile Seq';
            app.ComputerListBoxLabel = uilabel(app.NewTileTab);
            app.ComputerListBoxLabel.Text = 'Computer';
            app.ComputerListBox = uilistbox(app.NewTileTab);
            app.ComputerListBox.ValueChangedFcn = createCallbackFcn(app, @ComputerListBoxValueChanged, true);
            app.PortsListBoxLabel = uilabel(app.NewTileTab);
            app.PortsListBoxLabel.Text = 'Ports';
            app.PortsListBox = uilistbox(app.NewTileTab);
            app.PortsListBox.ValueChangedFcn = createCallbackFcn(app, @PortsListBoxValueChanged, true);
            app.TileSequencesListBoxLabel = uilabel(app.NewTileTab);
            app.TileSequencesListBoxLabel.Text = 'Tile Sequences';
            app.TileSequencesListBox = uilistbox(app.NewTileTab);
            app.TileSequencesListBox.ValueChangedFcn = createCallbackFcn(app, @TileSequencesListBoxValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = manageDevices

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end