classdef registrationDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RegisterPhytomorphandHTCondorPanel  matlab.ui.container.Panel
        RoomEditFieldLabel              matlab.ui.control.Label
        RoomEditField                   matlab.ui.control.EditField
        BuildingEditFieldLabel          matlab.ui.control.Label
        BuildingEditField               matlab.ui.control.EditField
        InstitutionEditFieldLabel       matlab.ui.control.Label
        InstitutionEditField            matlab.ui.control.EditField
        PrincipalInvestigatorEditFieldLabel  matlab.ui.control.Label
        PrincipalInvestigatorEditField  matlab.ui.control.EditField
        EmailEditFieldLabel             matlab.ui.control.Label
        EmailEditField                  matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        UserNameEditFieldLabel          matlab.ui.control.Label
        UserNameEditField               matlab.ui.control.EditField
        PasswordEditFieldLabel          matlab.ui.control.Label
        PasswordEditField               matlab.ui.control.EditField
    end


    properties (Access = private)
        pwRead = []; % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            M = double(imread('~/phytoMorphTK/logo.png'))/255;
            M = imresize(M,2);
            %{
            I = double(imread('~/phytoMorphTK/pmLogo.png'))/255;
            J = double(imread('~/phytoMorphTK/htCondor.png'))/255;
            
            sz = size(J);
            J = reshape(J,[prod(sz(1:2)) sz(3)]);
            fidx = find(all(bsxfun(@eq,J,[1 1 1]),2));
            J(fidx,:) = repmat([.94 .94 .94],[numel(fidx) 1]);
            J = reshape(J,sz);
            
            sz = size(I);
            I = reshape(I,[prod(sz(1:2)) sz(3)]);
            fidx = find(all(bsxfun(@eq,I,[1 1 1]),2));
            I(fidx,:) = repmat([.94 .94 .94],[numel(fidx) 1]);
            I = reshape(I,sz);
            
            imshow(J,'parent',app.UIAxes);
            imshow(I,'parent',app.UIAxes_2);
            %}
            imshow(M,'parent',app.UIAxes);
        end

        % Value changing function: PasswordEditField
        function PasswordEditFieldValueChanging(app, event)
           
            %event.Value='l';
            %app.PasswordEditField.Value = [app.PasswordEditField.Value '*'];
        end

        % Value changed function: PasswordEditField
        function PasswordEditFieldValueChanged(app, event)
            
            fprintf('callR');
            event.Value
            app.pwRead = [pwRead event.Value];
            
            value = get(app.PasswordEditField,'Value');
            cnt = 1;
            while isempty(value)
                value = get(app.PasswordEditField,'Value');
                pause(eps);
                if ~isempty(value)
                    while ~strcmp(value(end),event.Value)
                         value = get(app.PasswordEditField,'Value');
                         pause(eps);
                    end
                end
                cnt = cnt + 1;
            end
            
            
            newValue = repmat('*',[1 numel(value)]);
            set(app.PasswordEditField,'Value',newValue);
            v = get(app.PasswordEditField,'Value');
            while ~strcmp(v,newValue)
                fprintf(['inloop.\n'])
                pause(eps);
                set(app.PasswordEditField,'Value',newValue);
                v = get(app.PasswordEditField,'Value');
            end
            done = 1;
            
            %event.Value
            %value = app.PasswordEditField.Value;
            %newValue = repmat('*',[1 numel(value+1)]);
            %app.PasswordEditField.Value = newValue;
            %here=1;
            %app.PasswordEditField.Value = '*';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RegisterPhytomorphandHTCondorPanel = uipanel(app.UIFigure);
            app.RegisterPhytomorphandHTCondorPanel.Title = 'Register Phytomorph and HTCondor';
            app.RoomEditFieldLabel = uilabel(app.RegisterPhytomorphandHTCondorPanel);
            app.RoomEditFieldLabel.Text = 'Room #';
            app.RoomEditField = uieditfield(app.RegisterPhytomorphandHTCondorPanel, 'text');
            app.BuildingEditFieldLabel = uilabel(app.RegisterPhytomorphandHTCondorPanel);
            app.BuildingEditFieldLabel.Text = 'Building';
            app.BuildingEditField = uieditfield(app.RegisterPhytomorphandHTCondorPanel, 'text');
            app.InstitutionEditFieldLabel = uilabel(app.RegisterPhytomorphandHTCondorPanel);
            app.InstitutionEditFieldLabel.Text = 'Institution';
            app.InstitutionEditField = uieditfield(app.RegisterPhytomorphandHTCondorPanel, 'text');
            app.PrincipalInvestigatorEditFieldLabel = uilabel(app.RegisterPhytomorphandHTCondorPanel);
            app.PrincipalInvestigatorEditFieldLabel.Text = 'Principal Investigator';
            app.PrincipalInvestigatorEditField = uieditfield(app.RegisterPhytomorphandHTCondorPanel, 'text');
            app.EmailEditFieldLabel = uilabel(app.RegisterPhytomorphandHTCondorPanel);
            app.EmailEditFieldLabel.Text = 'Email';
            app.EmailEditField = uieditfield(app.RegisterPhytomorphandHTCondorPanel, 'text');
            app.NameEditFieldLabel = uilabel(app.RegisterPhytomorphandHTCondorPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.RegisterPhytomorphandHTCondorPanel, 'text');
            app.UIAxes = uiaxes(app.RegisterPhytomorphandHTCondorPanel);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UserNameEditFieldLabel = uilabel(app.Panel);
            app.UserNameEditFieldLabel.Text = 'User Name';
            app.UserNameEditField = uieditfield(app.Panel, 'text');
            app.PasswordEditFieldLabel = uilabel(app.Panel);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.Panel, 'text');
            app.PasswordEditField.ValueChangedFcn = createCallbackFcn(app, @PasswordEditFieldValueChanged, true);
            app.PasswordEditField.ValueChangingFcn = createCallbackFcn(app, @PasswordEditFieldValueChanging, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = registrationDialog

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end